classdef GMGDataPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GMGDataPickerUIFigure    matlab.ui.Figure
        ContinueButton           matlab.ui.control.Button
        CorrectButton            matlab.ui.control.Button
        HitentertostopLabel      matlab.ui.control.Label
        OverlayDataPanel         matlab.ui.container.Panel
        TransparencySlider       matlab.ui.control.Slider
        TransparencySliderLabel  matlab.ui.control.Label
        SwitchVolumeData_2       matlab.ui.control.Switch
        VolumeDataDropDown_2     matlab.ui.control.DropDown
        XMin_2                   matlab.ui.control.NumericEditField
        XMax_2                   matlab.ui.control.NumericEditField
        YMin_2                   matlab.ui.control.NumericEditField
        YMax_2                   matlab.ui.control.NumericEditField
        SetLimitsButton_2        matlab.ui.control.Button
        Panel_3                  matlab.ui.container.Panel
        VolumedataLabel          matlab.ui.control.Label
        SwitchVolumeData         matlab.ui.control.Switch
        VolumeDataDropDown       matlab.ui.control.DropDown
        Panel_2                  matlab.ui.container.Panel
        SurfacedataLabel         matlab.ui.control.Label
        SwitchSurfaceData        matlab.ui.control.Switch
        Panel                    matlab.ui.container.Panel
        PointdataLabel           matlab.ui.control.Label
        SwitchPointData          matlab.ui.control.Switch
        ColormapPanel            matlab.ui.container.Panel
        CMaxEditField_2          matlab.ui.control.NumericEditField
        CMinEditField_2          matlab.ui.control.NumericEditField
        ColormapDropDown_2       matlab.ui.control.DropDown
        CMaxEditField            matlab.ui.control.NumericEditField
        CMaxEditFieldLabel       matlab.ui.control.Label
        CMinEditField            matlab.ui.control.NumericEditField
        CMinEditFieldLabel       matlab.ui.control.Label
        ColormapDropDown         matlab.ui.control.DropDown
        ProfileLimitsPanel       matlab.ui.container.Panel
        XMin                     matlab.ui.control.NumericEditField
        XMax                     matlab.ui.control.NumericEditField
        YMin                     matlab.ui.control.NumericEditField
        YMax                     matlab.ui.control.NumericEditField
        SetLimitsButton          matlab.ui.control.Button
        ClearPicksButton         matlab.ui.control.Button
        ClearDataButton          matlab.ui.control.Button
        LoadDataButton           matlab.ui.control.Button
        SavePicksButton          matlab.ui.control.Button
        LoadPicksButton          matlab.ui.control.Button
        StartPickingButton       matlab.ui.control.Button
        GeophysicalModelGeneratorDataPickerLabel  matlab.ui.control.Label
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create a structure to hold all the data for the profiles
            app.GMGDataPickerUIFigure.UserData = [];
        end

        % Button pushed function: LoadDataButton
        function OpendatasetMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                % disp('User selected Cancel');
                % nothing happens now
            else
                % clear the axes
                % clear all axes and User data
                app.GMGDataPickerUIFigure.UserData = [];
                cla(app.UIAxes)

                % load the file into the user data structure
                app.GMGDataPickerUIFigure.UserData = load([path,file]);
                VolData = app.GMGDataPickerUIFigure.UserData.ProfileData.VolData;

                X = VolData.fields.x_profile;
                Y = VolData.depth.val;
                app.GMGDataPickerUIFigure.UserData.FieldData = rmfield(VolData.fields,'x_profile');
                FieldData = app.GMGDataPickerUIFigure.UserData.FieldData;
                % set up the dropdown menus for volume data
                VolFieldCell = fields(app.GMGDataPickerUIFigure.UserData.FieldData);
                app.VolumeDataDropDown.Items   = VolFieldCell;
                app.VolumeDataDropDown_2.Items = VolFieldCell;

                % plot the first volume dataset in all tabs so that we have
                % something to see
                app.GMGDataPickerUIFigure.UserData.VolHandle(1) = pcolor(app.UIAxes,X,Y,FieldData.(app.VolumeDataDropDown.Items{1}));
                set(app.UIAxes,'YDir','normal')
                axis(app.UIAxes,'equal')
                axis(app.UIAxes,'tight')
                shading(app.UIAxes,'flat')
                colorbar(app.UIAxes,"eastoutside")
                colormap(app.UIAxes,"turbo")
                title(app.UIAxes,file)
                hold(app.UIAxes,'on')
                app.SwitchVolumeData.Value = 'On';
                
                % plot the first volume data on top as overlay axes
                app.GMGDataPickerUIFigure.UserData.VolHandle(2) = pcolor(app.UIAxes_2,X,Y,FieldData.(app.VolumeDataDropDown_2.Items{1}));
                app.UIAxes_2.Color = 'none';
                set(app.UIAxes_2,'YDir','normal','box','on','layer','top')
                axis(app.UIAxes_2,'equal')
                axis(app.UIAxes_2,'tight')
                shading(app.UIAxes_2,'flat')
                app.UIAxes_2.InnerPosition = app.UIAxes.InnerPosition;
                app.SwitchVolumeData_2.Value = 'Off';
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData = zeros(size(app.GMGDataPickerUIFigure.UserData.VolHandle(2).CData));
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).FaceAlpha = 'flat';
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaDataMapping = 'none';
                %set(app.UIAxes,'YDir','normal')
                %axis(app.UIAxes,'equal')
                %axis(app.UIAxes,'tight')
                %shading(app.UIAxes,'interp')
                colorbar(app.UIAxes,"eastoutside")
                colormap(app.UIAxes,"turbo")
                title(app.UIAxes,file)
                hold(app.UIAxes,'on')
                app.SwitchVolumeData.Value = 'On';

                % plot the surface data --> all of it                
                for isurf = 1:length(app.GMGDataPickerUIFigure.UserData.ProfileData.SurfData)
                    X = app.GMGDataPickerUIFigure.UserData.ProfileData.SurfData{isurf}.fields.x_profile;
                    Y = app.GMGDataPickerUIFigure.UserData.ProfileData.SurfData{isurf}.depth.val;
                    
                    app.SwitchSurfaceData.Value = 'On';
                    app.GMGDataPickerUIFigure.UserData.SurfHandle(isurf) = plot(app.UIAxes,X,Y,'k-','LineWidth',2);
                end

                % plot the point data --> all of it
                for ipoint = 1:length(app.GMGDataPickerUIFigure.UserData.ProfileData.PointData)
                    XP = app.GMGDataPickerUIFigure.UserData.ProfileData.PointData{ipoint}.fields.x_profile; % diatance along profile is already computed in km!
                    YP = app.GMGDataPickerUIFigure.UserData.ProfileData.PointData{ipoint}.depth.val;

                    app.SwitchPointData.Value = 'On';
                    app.GMGDataPickerUIFigure.UserData.PointHandle(ipoint) = plot(app.UIAxes,XP,YP,'ko','MarkerSize',8,'MarkerFaceColor','r');
                end
                
                % set xlim to the tomography data
                xlim(app.UIAxes,[min(X(:)) max(X(:))])


                disp(['User selected ', fullfile(path,file)]);
            end
        end

        % Callback function
        function LoadDataButtonPushed(app, event)
            OpendatasetMenuSelected(app, event)
        end

        % Value changed function: VolumeDataDropDown
        function VolumeDataDropDownValueChanged(app, event)
            value = app.VolumeDataDropDown.Value;
            FieldData = app.GMGDataPickerUIFigure.UserData.FieldData;
            app.GMGDataPickerUIFigure.UserData.VolHandle(1).CData = FieldData.(value);
        end

        % Value changed function: CMinEditField
        function CMinEditFieldValueChanged(app, event)
            value = app.CMinEditField.Value;
            tmp = version("-release");
            if str2double(tmp(1:4))<2022
                caxis(app.UIAxes,[value max(clim(app.UIAxes))])
            else
                clim(app.UIAxes,[value max(clim(app.UIAxes))])
            end

        end

        % Value changed function: CMaxEditField
        function CMaxEditFieldValueChanged(app, event)
            value = app.CMaxEditField.Value;
            tmp = version("-release");
            if str2double(tmp(1:4))<2022
                caxis(app.UIAxes,[min(clim(app.UIAxes)) value])
            else
                clim(app.UIAxes,[min(clim(app.UIAxes)) value])
            end
        end

        % Button pushed function: SetLimitsButton
        function SetLimitsButtonPushed(app, event)
            app.UIAxes.XLim = [app.XMin.Value app.XMax.Value];
            app.UIAxes.YLim = [app.YMin.Value app.YMax.Value];

            app.UIAxes_2.XLim = [app.XMin.Value app.XMax.Value];
            app.UIAxes_2.YLim = [app.YMin.Value app.YMax.Value];
            app.UIAxes_2.InnerPosition = app.UIAxes.InnerPosition;

            app.XMin_2.Value = app.XMin.Value;
            app.XMax_2.Value = app.XMax.Value;
            app.YMin_2.Value = app.YMin.Value;
            app.YMax_2.Value = app.YMax.Value;

        end

        % Value changed function: SwitchVolumeData
        function SwitchVolumeDataValueChanged(app, event)
            value = app.SwitchVolumeData.Value;
            if strcmp(value,'On')
                app.GMGDataPickerUIFigure.UserData.VolHandle(1).Visible = true;
            else
                app.GMGDataPickerUIFigure.UserData.VolHandle(1).Visible = false;
            end
        end

        % Button pushed function: StartPickingButton
        function StartPickingButtonPushed(app, event)
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.GMGDataPickerUIFigure.HandleVisibility;        % Current status
            app.GMGDataPickerUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.GMGDataPickerUIFigure)       % Make fig current
            
            % pick
            [x,y] = ginputc('MarkerSize',5,'ShowPoints',true); % collect as many points as you wish, hit enter to stop
            app.GMGDataPickerUIFigure.UserData.PickedX = x;
            app.GMGDataPickerUIFigure.UserData.PickedZ = y;
            % the picked points disappear after the function has been
            % executed, so here we plot it again
            app.GMGDataPickerUIFigure.UserData.hPick = plot3(app.UIAxes,app.GMGDataPickerUIFigure.UserData.PickedX,app.GMGDataPickerUIFigure.UserData.PickedZ,110e3*ones(size(app.GMGDataPickerUIFigure.UserData.PickedX)),'kv-','MarkerFaceColor','w','MarkerEdgeColor','k','MarkerSize',8,'LineWidth',2);
            app.GMGDataPickerUIFigure.HandleVisibility = fhv;        % return original state

        end

        % Callback function
        function StopPickingButtonPushed(app, event)
            app.GMGDataPickerUIFigure.UserData.KeepPicking = false;
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            % clear all axes and User data
            app.GMGDataPickerUIFigure.UserData = [];
            cla(app.UIAxes)
        end

        % Button pushed function: SavePicksButton
        function SavePicksButtonPushed(app, event)
            % open save file dialogue
            [FILE,PATH] = uiputfile('*.txt');
            X = app.GMGDataPickerUIFigure.UserData.PickedX(~isnan(app.GMGDataPickerUIFigure.UserData.PickedX));
            Z = app.GMGDataPickerUIFigure.UserData.PickedZ(~isnan(app.GMGDataPickerUIFigure.UserData.PickedX));
            if FILE~=0
                % save the picked data data as ascii
                T = table(X,Z);
                writetable(T,[PATH,FILE])
            end
        end

        % Button pushed function: ClearPicksButton
        function ClearPicksButtonPushed(app, event)
            app.GMGDataPickerUIFigure.UserData.PickedX = [];
            app.GMGDataPickerUIFigure.UserData.PickedZ = [];
            app.GMGDataPickerUIFigure.UserData.hPick.XData = [];
            app.GMGDataPickerUIFigure.UserData.hPick.YData = [];
        end

        % Button pushed function: LoadPicksButton
        function LoadPicksButtonPushed(app, event)
            % load profile file -> should be mat or text file
            [FILE,PATH] = uigetfile("*.txt");

            if FILE~=0
                T = readtable([PATH,FILE]);
                % plot the picks
                plot3(app.UIAxes,T.X,T.Z,1e3*ones(size(T.X)),'ko-','MarkerFaceColor',[0.3 0.3 0.3])
            end
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            if strcmp(value,'vik')
                cmap = load("vik.mat");
                colormap(app.UIAxes,cmap.vik)
            elseif strcmp(value,'vikflipped')
                cmap = load("vik.mat");
                colormap(app.UIAxes,flipud(cmap.vik))
            else
                colormap(app.UIAxes,value)
            end

        end

        % Value changed function: SwitchSurfaceData
        function SwitchSurfaceDataValueChanged(app, event)
            value = app.SwitchSurfaceData.Value;
            if strcmp(value,'On')
                [app.GMGDataPickerUIFigure.UserData.SurfHandle.Visible] = deal(true);
            else
                [app.GMGDataPickerUIFigure.UserData.SurfHandle.Visible] = deal(false);
            end
        end

        % Value changed function: SwitchPointData
        function SwitchPointDataValueChanged(app, event)
            value = app.SwitchPointData.Value;
            if strcmp(value,'On')
                [app.GMGDataPickerUIFigure.UserData.PointHandle.Visible] = deal(true);
            else
                [app.GMGDataPickerUIFigure.UserData.PointHandle.Visible] = deal(false);
            end
        end

        % Value changed function: SwitchVolumeData_2
        function SwitchVolumeData_2ValueChanged(app, event)
            value = app.SwitchVolumeData_2.Value;
            if strcmp(value,'On')
                %app.GMGDataPickerUIFigure.UserData.VolHandle(2).Visible = true;
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData = ones(size(app.GMGDataPickerUIFigure.UserData.VolHandle(2).CData));
            else
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData = zeros(size(app.GMGDataPickerUIFigure.UserData.VolHandle(2).CData));
            end
            %app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaDataMapping = 'none';
        end

        % Value changed function: VolumeDataDropDown_2
        function VolumeDataDropDown_2ValueChanged(app, event)
            value = app.VolumeDataDropDown_2.Value;
            FieldData = app.GMGDataPickerUIFigure.UserData.FieldData;
            app.GMGDataPickerUIFigure.UserData.VolHandle(2).CData = FieldData.(value);
            %app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaDataMapping = 'none';
        end

        % Value changed function: CMinEditField_2
        function CMinEditField_2ValueChanged(app, event)
            value = app.CMinEditField_2.Value;
            tmp = version("-release");
            if str2double(tmp(1:4))<2022
                caxis(app.UIAxes_2,[value max(clim(app.UIAxes_2))])
            else
                clim(app.UIAxes_2,[value max(clim(app.UIAxes_2))])
            end
        end

        % Value changed function: CMaxEditField_2
        function CMaxEditField_2ValueChanged(app, event)
            value = app.CMaxEditField_2.Value;
            tmp = version("-release");
            if str2double(tmp(1:4))<2022
                caxis(app.UIAxes_2,[min(clim(app.UIAxes_2)) value])
            else
                clim(app.UIAxes_2,[min(clim(app.UIAxes_2)) value])
            end
        end

        % Value changed function: ColormapDropDown_2
        function ColormapDropDown_2ValueChanged(app, event)
            value = app.ColormapDropDown_2.Value;
            if strcmp(value,'vik')
                cmap = load("vik.mat");
                colormap(app.UIAxes_2,cmap.vik)
            elseif strcmp(value,'vikflipped')
                cmap = load("vik.mat");
                colormap(app.UIAxes_2,flipud(cmap.vik))
            else
                colormap(app.UIAxes_2,value)
            end

        end

        % Button pushed function: SetLimitsButton_2
        function SetLimitsButton_2Pushed(app, event)
            ind = app.GMGDataPickerUIFigure.UserData.VolHandle(2).XData <= app.XMax_2.Value & app.GMGDataPickerUIFigure.UserData.VolHandle(2).XData >= app.XMin_2.Value & app.GMGDataPickerUIFigure.UserData.VolHandle(2).YData >= app.YMin_2.Value & app.GMGDataPickerUIFigure.UserData.VolHandle(2).YData <= app.YMax_2.Value;
            app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(ind) = app.TransparencySlider.Value;
            app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(~ind) = 0;
            %app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaDataMapping = 'none';
        end

        % Value changed function: TransparencySlider
        function TransparencySliderValueChanged(app, event)
            value = app.TransparencySlider.Value;
            if any(app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(:) > 0)
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData ~= 0) = value;
            else
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(:) = value;
            end
        end

        % Value changing function: TransparencySlider
        function TransparencySliderValueChanging(app, event)
            changingValue = event.Value;
            if any(app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(:) > 0)
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData ~= 0) = changingValue;
            else
                app.GMGDataPickerUIFigure.UserData.VolHandle(2).AlphaData(:) = changingValue;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GMGDataPickerUIFigure = uifigure('Visible', 'off');
            app.GMGDataPickerUIFigure.Name = 'GMGDataPicker';
            app.UIAxes = uiaxes(app.GMGDataPickerUIFigure);
            xlabel(app.UIAxes, 'x [km]')
            ylabel(app.UIAxes, 'depth [km]')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'turbo')
            app.UIAxes_2 = uiaxes(app.GMGDataPickerUIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            colormap(app.UIAxes_2, 'turbo')
            app.GeophysicalModelGeneratorDataPickerLabel = uilabel(app.GMGDataPickerUIFigure);
            app.GeophysicalModelGeneratorDataPickerLabel.Text = 'GeophysicalModelGenerator Data Picker';
            app.StartPickingButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.StartPickingButton.ButtonPushedFcn = createCallbackFcn(app, @StartPickingButtonPushed, true);
            app.StartPickingButton.Text = 'Start Picking';
            app.LoadPicksButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.LoadPicksButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPicksButtonPushed, true);
            app.LoadPicksButton.Text = 'Load Picks';
            app.SavePicksButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.SavePicksButton.ButtonPushedFcn = createCallbackFcn(app, @SavePicksButtonPushed, true);
            app.SavePicksButton.Text = 'SavePicks';
            app.LoadDataButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @OpendatasetMenuSelected, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ClearDataButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.ClearPicksButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.ClearPicksButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPicksButtonPushed, true);
            app.ClearPicksButton.Text = 'Clear Picks';
            app.ProfileLimitsPanel = uipanel(app.GMGDataPickerUIFigure);
            app.ProfileLimitsPanel.Title = 'Profile Limits';
            app.SetLimitsButton = uibutton(app.ProfileLimitsPanel, 'push');
            app.SetLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @SetLimitsButtonPushed, true);
            app.SetLimitsButton.Text = 'Set Limits';
            app.YMax = uieditfield(app.ProfileLimitsPanel, 'numeric');
            app.YMin = uieditfield(app.ProfileLimitsPanel, 'numeric');
            app.XMax = uieditfield(app.ProfileLimitsPanel, 'numeric');
            app.XMin = uieditfield(app.ProfileLimitsPanel, 'numeric');
            app.ColormapPanel = uipanel(app.GMGDataPickerUIFigure);
            app.ColormapPanel.Title = 'Colormap';
            app.ColormapDropDown = uidropdown(app.ColormapPanel);
            app.ColormapDropDown.Items = {'turbo', 'hot', 'spring', 'summer', 'autumn', 'winter', 'gray', 'vik', 'vikflipped'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'turbo';
            app.CMinEditFieldLabel = uilabel(app.ColormapPanel);
            app.CMinEditFieldLabel.Text = 'CMin';
            app.CMinEditField = uieditfield(app.ColormapPanel, 'numeric');
            app.CMinEditField.ValueChangedFcn = createCallbackFcn(app, @CMinEditFieldValueChanged, true);
            app.CMaxEditFieldLabel = uilabel(app.ColormapPanel);
            app.CMaxEditFieldLabel.Text = 'CMax';
            app.CMaxEditField = uieditfield(app.ColormapPanel, 'numeric');
            app.CMaxEditField.ValueChangedFcn = createCallbackFcn(app, @CMaxEditFieldValueChanged, true);
            app.CMaxEditField.Value = 1;
            app.ColormapDropDown_2 = uidropdown(app.ColormapPanel);
            app.ColormapDropDown_2.Items = {'turbo', 'hot', 'spring', 'summer', 'autumn', 'winter', 'gray', 'vik', 'vikflipped'};
            app.ColormapDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDown_2ValueChanged, true);
            app.ColormapDropDown_2.Value = 'turbo';
            app.CMinEditField_2 = uieditfield(app.ColormapPanel, 'numeric');
            app.CMinEditField_2.ValueChangedFcn = createCallbackFcn(app, @CMinEditField_2ValueChanged, true);
            app.CMaxEditField_2 = uieditfield(app.ColormapPanel, 'numeric');
            app.CMaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @CMaxEditField_2ValueChanged, true);
            app.CMaxEditField_2.Value = 1;
            app.Panel = uipanel(app.GMGDataPickerUIFigure);
            app.SwitchPointData = uiswitch(app.Panel, 'slider');
            app.SwitchPointData.ValueChangedFcn = createCallbackFcn(app, @SwitchPointDataValueChanged, true);
            app.PointdataLabel = uilabel(app.Panel);
            app.PointdataLabel.Text = 'Point data';
            app.Panel_2 = uipanel(app.GMGDataPickerUIFigure);
            app.SwitchSurfaceData = uiswitch(app.Panel_2, 'slider');
            app.SwitchSurfaceData.ValueChangedFcn = createCallbackFcn(app, @SwitchSurfaceDataValueChanged, true);
            app.SurfacedataLabel = uilabel(app.Panel_2);
            app.SurfacedataLabel.Text = 'Surface data';
            app.Panel_3 = uipanel(app.GMGDataPickerUIFigure);
            app.VolumeDataDropDown = uidropdown(app.Panel_3);
            app.VolumeDataDropDown.ValueChangedFcn = createCallbackFcn(app, @VolumeDataDropDownValueChanged, true);
            app.SwitchVolumeData = uiswitch(app.Panel_3, 'slider');
            app.SwitchVolumeData.ValueChangedFcn = createCallbackFcn(app, @SwitchVolumeDataValueChanged, true);
            app.VolumedataLabel = uilabel(app.Panel_3);
            app.VolumedataLabel.Text = 'Volume data';
            app.OverlayDataPanel = uipanel(app.GMGDataPickerUIFigure);
            app.OverlayDataPanel.Title = 'Overlay Data';
            app.SetLimitsButton_2 = uibutton(app.OverlayDataPanel, 'push');
            app.SetLimitsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetLimitsButton_2Pushed, true);
            app.SetLimitsButton_2.Text = 'Set Limits';
            app.YMax_2 = uieditfield(app.OverlayDataPanel, 'numeric');
            app.YMin_2 = uieditfield(app.OverlayDataPanel, 'numeric');
            app.XMax_2 = uieditfield(app.OverlayDataPanel, 'numeric');
            app.XMin_2 = uieditfield(app.OverlayDataPanel, 'numeric');
            app.VolumeDataDropDown_2 = uidropdown(app.OverlayDataPanel);
            app.VolumeDataDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VolumeDataDropDown_2ValueChanged, true);
            app.SwitchVolumeData_2 = uiswitch(app.OverlayDataPanel, 'slider');
            app.SwitchVolumeData_2.ValueChangedFcn = createCallbackFcn(app, @SwitchVolumeData_2ValueChanged, true);
            app.TransparencySliderLabel = uilabel(app.OverlayDataPanel);
            app.TransparencySliderLabel.Text = 'Transparency';
            app.TransparencySlider = uislider(app.OverlayDataPanel);
            app.TransparencySlider.Limits = [0 1];
            app.TransparencySlider.MajorTicks = [0 0.5 1];
            app.TransparencySlider.ValueChangedFcn = createCallbackFcn(app, @TransparencySliderValueChanged, true);
            app.TransparencySlider.ValueChangingFcn = createCallbackFcn(app, @TransparencySliderValueChanging, true);
            app.HitentertostopLabel = uilabel(app.GMGDataPickerUIFigure);
            app.HitentertostopLabel.Text = 'Hit "enter" to stop';
            app.CorrectButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.CorrectButton.Text = 'Correct';
            app.ContinueButton = uibutton(app.GMGDataPickerUIFigure, 'push');
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.GMGDataPickerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GMGDataPicker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GMGDataPickerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GMGDataPickerUIFigure)
        end
    end
end