classdef ProfileSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SetLimitsButton       matlab.ui.control.Button
        LatMin                matlab.ui.control.EditField
        LatMax                matlab.ui.control.EditField
        LonMax                matlab.ui.control.EditField
        LonMin                matlab.ui.control.EditField
        ClearprofilesButton   matlab.ui.control.Button
        SaveprofilesButton    matlab.ui.control.Button
        PicknewprofileButton  matlab.ui.control.Button
        ModifyProfilesButton  matlab.ui.control.Button
        LoadProfilesButton    matlab.ui.control.Button
        LoadTopoButton        matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create a structure to hold all the data for the profiles
            app.UIFigure.UserData = struct("num",[],"start",[],"end",[],... % these are the actual values
                "start_handle",[],"end_handle",[],"line_handle",[],"text_handle",[]); % these are plotting handles for interactive data modification
        end

        % Button pushed function: LoadTopoButton
        function LoadTopoButtonPushed(app, event)
            % load colormap
            load('oleron.mat','oleron')

            % load topo file
            [FILE,PATH] = uigetfile("*.tiff");
            
            if FILE~=0
            % read the data
            [A,R] = readgeoraster([PATH,FILE]);
            % plot
            geoshow(app.UIAxes,double(A),R,'DisplayType','surface')
            hold(app.UIAxes,"on")
            colormap(app.UIAxes,oleron),clim(app.UIAxes,[-4 4]*1e3)
            set(app.UIAxes,'box','on','layer','top','XLim',R.LongitudeLimits,'YLim',R.LatitudeLimits)
            daspect(app.UIAxes,[1.65 1 1]) % this should approximately the scaling factor for Mercator so that our angles are ok
            app.LonMin.Value = num2str(R.LongitudeLimits(1));
            app.LonMax.Value = num2str(R.LongitudeLimits(2));
            app.LatMin.Value = num2str(R.LatitudeLimits(1));
            app.LatMax.Value = num2str(R.LatitudeLimits(2));
            end
        end

        % Button pushed function: LoadProfilesButton
        function LoadProfilesButtonPushed(app, event)
            % load profile file -> should be mat or text file
            [FILE,PATH] = uigetfile("*.txt");

            if FILE~=0
                T = readtable([PATH,FILE]);

                % plot the profiles along with their numbers
                for iprofile=1:size(T,1)
                    num = T.Num(iprofile);
                    hold(app.UIAxes,'on')
                    h_line  = plot3(app.UIAxes,[T.LonStart(iprofile) T.LonEnd(iprofile)], [T.LatStart(iprofile) T.LatEnd(iprofile)],[10e3 10e3],'r-','LineWidth',2);
                    h_start = plot3(app.UIAxes,T.LonStart(iprofile),T.LatStart(iprofile),10e3,'ro','MarkerFaceColor','r');
                    h_end   = plot3(app.UIAxes,T.LonEnd(iprofile),T.LatEnd(iprofile),10e3,'ro','MarkerFaceColor','r');
                    %h_text  = text(app.UIAxes,T.LonEnd(iprofile),T.LatEnd(iprofile),10e3,[' ',num2str(T.Num(iprofile))],'Color','red','FontSize',14);

                    % add to existing data - this could be done in a smarter
                    % way, but for now this is quick and dirty...
%                     app.UIFigure.UserData.num           = [app.UIFigure.UserData.num;num]; % this may be problematic if we already picked some profiles...
%                     app.UIFigure.UserData.start         = [app.UIFigure.UserData.start; T.LonStart(iprofile),T.LatStart(iprofile)];
%                     app.UIFigure.UserData.end           = [app.UIFigure.UserData.end; [T.LonEnd(iprofile),T.LatEnd(iprofile)]];
                    app.UIFigure.UserData.start_handle  = [app.UIFigure.UserData.start_handle; h_start];
                    app.UIFigure.UserData.end_handle    = [app.UIFigure.UserData.end_handle; h_end];
                    app.UIFigure.UserData.line_handle   = [app.UIFigure.UserData.line_handle; h_line];
                    %app.UIFigure.UserData.text_handle   = [app.UIFigure.UserData.text_handle; h_text];
                end
                hold(app.UIAxes,'off')
            end
        end

        % Button pushed function: PicknewprofileButton
        function PicknewprofileButtonPushed(app, event)
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
    
            % use ginputuiax to create a new profile
            % [lon1,lat1] = ginput(1); % took forever
            [lon1,lat1] = ginputuiax(app.UIAxes,1);
            h_start = plot3(app.UIAxes,lon1,lat1,10e3,'ro','MarkerFaceColor','r');

            % [lon2,lat2] = ginput(1);
            [lon2,lat2] = ginputuiax(app.UIAxes,1);
            h_line = plot3(app.UIAxes,[lon1 lon2],[lat1 lat2],[10e3 10e3],'r-','LineWidth',2);
            h_end = plot3(app.UIAxes,lon2,lat2,10e3,'ro','MarkerFaceColor','r');
            %h_text  = text(app.UIAxes,lon2,lat2,10e3,[' ',num2str(length(app.UIFigure.UserData.num)+1)],'Color','red','FontSize',14);

            % add to existing handles
            app.UIFigure.UserData.start_handle  = [app.UIFigure.UserData.start_handle; h_start];
            app.UIFigure.UserData.end_handle    = [app.UIFigure.UserData.end_handle; h_end];
            app.UIFigure.UserData.line_handle   = [app.UIFigure.UserData.line_handle; h_line];
            %app.UIFigure.UserData.text_handle   = [app.UIFigure.UserData.text_handle; h_text];

            app.UIFigure.HandleVisibility = fhv;        % return original state
        end

        % Button pushed function: SaveprofilesButton
        function SaveprofilesButtonPushed(app, event)
            % open save file dialogue
            [FILE,PATH] = uiputfile('*.txt');

            if FILE~=0
                % actually save the profile data as ascii, extract data
                % from handles
                Num         = [1:length(app.UIFigure.UserData.start_handle)]';
                LonStart    = cell2mat(get(app.UIFigure.UserData.start_handle,'XData'));
                LatStart    = cell2mat(get(app.UIFigure.UserData.start_handle,'YData'));
                LonEnd      = cell2mat(get(app.UIFigure.UserData.end_handle,'XData'));
                LatEnd      = cell2mat(get(app.UIFigure.UserData.end_handle,'YData'));
                T = table(Num,LonStart,LatStart,LonEnd,LatEnd);
                writetable(T,[PATH,FILE])
            end
        end

        % Button pushed function: ClearprofilesButton
        function ClearprofilesButtonPushed(app, event)
            % delete all handles and stored data
            app.UIFigure.UserData.num           = [];
            app.UIFigure.UserData.start         = [];
            app.UIFigure.UserData.end           = [];
            delete(app.UIFigure.UserData.start_handle);
            delete(app.UIFigure.UserData.end_handle);
            delete(app.UIFigure.UserData.line_handle);
            delete(app.UIFigure.UserData.text_handle);
        end

        % Button pushed function: ModifyProfilesButton
        function ModifyProfilesButtonPushed(app, event)
            % make all points draggable --> employ the function from file
            % exchange
            draggable(app.UIFigure.UserData.start_handle)
        end

        % Value changed function: LatMax
        function LatMaxValueChanged(app, event)
            value = app.LatMax.Value;
        end

        % Value changed function: LatMin
        function LatMinValueChanged(app, event)
            value = app.LatMin.Value;
        end

        % Value changed function: LonMin
        function LonMinValueChanged(app, event)
            value = app.LonMin.Value;
        end

        % Value changed function: LonMax
        function LonMaxValueChanged(app, event)
            value = app.LonMax.Value;
        end

        % Button pushed function: SetLimitsButton
        function SetLimitsButtonPushed(app, event)
            app.UIAxes.XLim = [ str2double(app.LonMin.Value)  str2double(app.LonMax.Value)];
            app.UIAxes.YLim = [ str2double(app.LatMin.Value)  str2double(app.LatMax.Value)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Latitude')
            ylabel(app.UIAxes, 'Longitude')
            app.LoadTopoButton = uibutton(app.UIFigure, 'push');
            app.LoadTopoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTopoButtonPushed, true);
            app.LoadTopoButton.Text = 'Load Topo';
            app.LoadProfilesButton = uibutton(app.UIFigure, 'push');
            app.LoadProfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProfilesButtonPushed, true);
            app.LoadProfilesButton.Text = 'Load Profiles';
            app.ModifyProfilesButton = uibutton(app.UIFigure, 'push');
            app.ModifyProfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyProfilesButtonPushed, true);
            app.ModifyProfilesButton.Text = 'Modify Profiles';
            app.PicknewprofileButton = uibutton(app.UIFigure, 'push');
            app.PicknewprofileButton.ButtonPushedFcn = createCallbackFcn(app, @PicknewprofileButtonPushed, true);
            app.PicknewprofileButton.Text = 'Pick new profile';
            app.SaveprofilesButton = uibutton(app.UIFigure, 'push');
            app.SaveprofilesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveprofilesButtonPushed, true);
            app.SaveprofilesButton.Text = 'Save profiles';
            app.ClearprofilesButton = uibutton(app.UIFigure, 'push');
            app.ClearprofilesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearprofilesButtonPushed, true);
            app.ClearprofilesButton.Text = 'Clear profiles';
            app.LonMin = uieditfield(app.UIFigure, 'text');
            app.LonMin.ValueChangedFcn = createCallbackFcn(app, @LonMinValueChanged, true);
            app.LonMax = uieditfield(app.UIFigure, 'text');
            app.LonMax.ValueChangedFcn = createCallbackFcn(app, @LonMaxValueChanged, true);
            app.LatMax = uieditfield(app.UIFigure, 'text');
            app.LatMax.ValueChangedFcn = createCallbackFcn(app, @LatMaxValueChanged, true);
            app.LatMin = uieditfield(app.UIFigure, 'text');
            app.LatMin.ValueChangedFcn = createCallbackFcn(app, @LatMinValueChanged, true);
            app.SetLimitsButton = uibutton(app.UIFigure, 'push');
            app.SetLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @SetLimitsButtonPushed, true);
            app.SetLimitsButton.Text = 'Set Limits';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProfileSelection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end